classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure           % UI Figure
        Label         matlab.ui.control.Label    % IdentOwl
        Label2        matlab.ui.control.Label    % A new touch of hope for ...
        Button        matlab.ui.control.Button   % Extract features
        Button2       matlab.ui.control.Button   % Result
        UIAxes        matlab.ui.control.UIAxes   % The selected image
        UIAxes2       matlab.ui.control.UIAxes   % The preprocessed image
        LabelTextArea matlab.ui.control.Label    % The letter is : 
        TextArea      matlab.ui.control.TextArea
        Button3       matlab.ui.control.Button   % Select image
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % StateButton value changed function
        function image(app)
           
        end

        % Button3 button pushed function
        function select_image(app)
             
           [fileName,pathName] = uigetfile('*.bmp')
           dname       = fullfile(pathName,fileName)
           filelist = dir([fileparts(dname) filesep '*.bmp']);
           fileNames = {filelist.name}'
           num_frames = (numel(filelist))
           setappdata ( app.Button3, 'im' , dname);
           current = pwd
           image = fileName ;
      %     
           
           %im=imread(image);
           %imshow(im);
           %image = imresize(im,[300,300]);
          % handles.image=image ;
            cd ( pathName )
            
            imshow(image);
            
            cd ( current)
          % imshow(image, app.UIAxes);


        end

        % Button button pushed function
        function extract_feature(app)
  im = getappdata(app.Button3,'im');
  im=imread(im);
  features = feature_extractor(im) ;
  setappdata ( app.Button , 'features' , features );
 
%im = imread(image)
%feature_extractor(im)

            
        end

        % Button2 button pushed function
        function result(app)
load('workspace_b(net_b).mat');
load('workspace_b(net_b).mat', 'net_b');
features = getappdata(app.Button,'features');
r =['A','B','C','D','E','F','G','H','I','J','k','l','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z'];
net_b(features)
[v,i]= max(net_b(features))
resultat = r(i)
%set(handles.TextArea, 'String',resultat) 
%resultat = getappdata(app.Button2,'resultat');


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'IdentOwl';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'A new touch of hope for those in darkness';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @extract_feature);
            app.Button.Text = 'Extract features';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @result);
            app.Button2.Text = 'Result';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'The selected image');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'The preprocessed image');
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = 'The letter is : ';
            app.TextArea = uitextarea(app.UIFigure);
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @select_image);
            app.Button3.Text = 'Select image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end